classdef Maze_GUI_DLC_sandbox < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        LeftPanel               matlab.ui.container.Panel
        UIAxes                  matlab.ui.control.UIAxes
        Slider                  matlab.ui.control.Slider
        PrevButton              matlab.ui.control.Button
        NextButton              matlab.ui.control.Button
        stepsiteEditFieldLabel  matlab.ui.control.Label
        stepsiteEditField       matlab.ui.control.NumericEditField
        Next10xButton           matlab.ui.control.Button
        Prev10xButton           matlab.ui.control.Button
        TextArea                matlab.ui.control.TextArea
        RightPanel              matlab.ui.container.Panel
        Box_in                  matlab.ui.control.Button
        C_L                     matlab.ui.control.Button
        C_R                     matlab.ui.control.Button
        F_L                     matlab.ui.control.Button
        F_R                     matlab.ui.control.Button
        R_Rew                   matlab.ui.control.Button
        L_Rew                   matlab.ui.control.Button
        NoteTextArea            matlab.ui.control.TextArea
        NoteButton              matlab.ui.control.Button
        UndoButton              matlab.ui.control.Button
        LoadData                matlab.ui.control.Button
        SaveButton              matlab.ui.control.Button
        CloseButton             matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        this_idx = [] % Description
        data = [];
        dt = [];
        t_range = [];
        states = [];
        states_times = [];
        state_vec = [];
        states_label = {};
        labels = {'Box_in', 'C_L', 'C_R', 'F_L', 'F_R', 'R_Rew', 'L_Rew', 'Note'};
        notes = {};
        blue = [0.3467    0.5360    0.6907]; 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadData
        function LoadDataButtonPushed(app, event)
            cla(app.UIAxes)
            app.data = evalin('base', 'behav');
            app.dt = mode(diff(app.data.time));
            app.t_range = [app.data.time(1), app.data.time(end)];
            app.state_vec = nan(1,length(app.data.time));
            app.this_idx = 1;
            app.stepsiteEditField.Value = .5;
            plot(app.UIAxes,app.data.position(:,1), app.data.position(:,2), '.', 'color', [0.9 .9 .9]);
            hold(app.UIAxes, 'on')
            plot(app.UIAxes,app.data.position(app.this_idx,1), app.data.position(app.this_idx,2), 'or', 'markersize', 14)
            this_title = ['Time: ' num2str(app.data.time(app.this_idx)/1000) 's'];
            app.UIAxes.Title.String =this_title ;
            app.Slider.Limits = app.t_range;
            if app.this_idx < length(app.data.time) - (app.dt*30)
                plot(app.UIAxes,app.data.position(app.this_idx:app.this_idx+(app.dt*30),1), app.data.position(app.this_idx:app.this_idx+(app.dt*30),2), '.','color', [app.blue .2], 'markersize', 4)
            else
                plot(app.UIAxes,app.data.position(app.this_idx:app.this_idx(end),1), app.data.position(app.this_idx:app.this_idx(end),2), '.','color', [app.blue .2], 'markersize', 4)
            end
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            cla(app.UIAxes)

            if app.this_idx >= length(app.data.time)
                app.this_idx = length(app.data.time);
            else
                app.this_idx = app.this_idx+(round(app.dt*app.stepsiteEditField.Value));
            end
            if app.this_idx >= length(app.data.time)
                app.this_idx = length(app.data.time);
            end
            fprintf('%.2fs in %.2fs steps\n', app.data.time(app.this_idx), app.stepsiteEditField.Value)
            plot(app.UIAxes,app.data.position(:,1), app.data.position(:,2), '.', 'color', [0.9 .9 .9])
            hold(app.UIAxes, 'on')
            if app.this_idx < length(app.data.time) - (app.dt*30)
                plot(app.UIAxes,app.data.position(app.this_idx:app.this_idx+(app.dt*30),1), app.data.position(app.this_idx:app.this_idx+(app.dt*30),2), '.','color', [app.blue .2], 'markersize', 4)
            else
                plot(app.UIAxes,app.data.position(app.this_idx:app.this_idx(end),1), app.data.position(app.this_idx:app.this_idx(end),2), '.','color', [app.blue .2], 'markersize', 4)
            end
            if app.this_idx < app.dt*5
                plot(app.UIAxes,app.data.position(1:app.this_idx,1), app.data.position(1:app.this_idx,2), '.r', 'markersize', 8)
            else
                plot(app.UIAxes,app.data.position(app.this_idx-(app.dt*5):app.this_idx,1), app.data.position(app.this_idx-(app.dt*5):app.this_idx,2), '.r', 'markersize', 8)
            end
            plot(app.UIAxes,app.data.position(app.this_idx,1), app.data.position(app.this_idx,2), 'or', 'markersize', 14)
            app.UIAxes.Title.String = ['Time: ' num2str(app.data.time(app.this_idx)) 's'];
        end

        % Button pushed function: PrevButton
        function PrevButtonPushed(app, event)
            cla(app.UIAxes)
            if app.this_idx <= 1
                app.this_idx = 1;
            else
                app.this_idx = app.this_idx-(round(app.dt*app.stepsiteEditField.Value));
            end
            if app.this_idx <1
                app.this_idx = 1;
            end
            fprintf('%.2fs in %.2fs steps\n', app.data.time(app.this_idx), app.stepsiteEditField.Value)
            plot(app.UIAxes,app.data.position(:,1), app.data.position(:,2), '.', 'color', [0.9 .9 .9])
            hold(app.UIAxes, 'on')
            if app.this_idx < length(app.data.time) - (app.dt*30)
                plot(app.UIAxes,app.data.position(app.this_idx:app.this_idx+(app.dt*30),1), app.data.position(app.this_idx:app.this_idx+(app.dt*30),2), '.','color', [app.blue .2], 'markersize', 4)
            else
                plot(app.UIAxes,app.data.position(app.this_idx:app.this_idx(end),1), app.data.position(app.this_idx:app.this_idx(end),2), '.','color', [app.blue .2], 'markersize', 4)
            end
            if app.this_idx < app.dt*5
                plot(app.UIAxes,app.data.position(1:app.this_idx,1), app.data.position(1:app.this_idx,2), '.r', 'markersize', 8)
            else
                plot(app.UIAxes,app.data.position(app.this_idx-(app.dt*5):app.this_idx,1), app.data.position(app.this_idx-(app.dt*5):app.this_idx,2), '.r', 'markersize', 8)
            end
            plot(app.UIAxes,app.data.position(app.this_idx,1), app.data.position(app.this_idx,2), 'or', 'markersize', 14)
            app.UIAxes.Title.String = ['Time: ' num2str(app.data.time(app.this_idx)) 's'];
        end

        % Button pushed function: Next10xButton
        function Next10xButtonPushed(app, event)
            cla(app.UIAxes)

            if app.this_idx == length(app.data.time)
                app.this_idx = app.this_idx;
            else
                app.this_idx = app.this_idx+(round(app.dt*10));
            end
            if app.this_idx >= length(app.data.time)
                app.this_idx = length(app.data.time);
            end
            fprintf('%.2fs in %.2fs steps\n', app.data.time(app.this_idx), 10)
            plot(app.UIAxes,app.data.position(:,1), app.data.position(:,2), '.', 'color', [0.9 .9 .9])
            hold(app.UIAxes, 'on')
            if app.this_idx < length(app.data.time) - (app.dt*30)
                plot(app.UIAxes,app.data.position(app.this_idx:app.this_idx+(app.dt*30),1), app.data.position(app.this_idx:app.this_idx+(app.dt*30),2), '.','color', [app.blue .2], 'markersize', 4)
            else
                plot(app.UIAxes,app.data.position(app.this_idx:app.this_idx(end),1), app.data.position(app.this_idx:app.this_idx(end),2), '.','color', [app.blue .2], 'markersize', 4)
            end
            if app.this_idx < app.dt*5
                plot(app.UIAxes,app.data.position(1:app.this_idx,1), app.data.position(1:app.this_idx,2), '.r', 'markersize', 8)
            else
                plot(app.UIAxes,app.data.position(app.this_idx-(app.dt*5):app.this_idx,1), app.data.position(app.this_idx-(app.dt*5):app.this_idx,2), '.r', 'markersize', 8)
            end
            plot(app.UIAxes,app.data.position(app.this_idx,1), app.data.position(app.this_idx,2), 'or', 'markersize', 14)
            app.UIAxes.Title.String = ['Time: ' num2str(app.data.time(app.this_idx)) 's'];
        end

        % Button pushed function: Prev10xButton
        function Prev10xButtonPushed(app, event)
            cla(app.UIAxes)
            if app.this_idx == 1
                app.this_idx = 1;
            else
                app.this_idx = app.this_idx-(round(app.dt*10));
            end
            if app.this_idx < 1
                app.this_idx = 1;
            end
            fprintf('%.2fs in %.2fs steps\n', app.data.time(app.this_idx), 10)
            plot(app.UIAxes,app.data.position(:,1), app.data.position(:,2), '.', 'color', [0.9 .9 .9])
            hold(app.UIAxes, 'on')
            if app.this_idx < length(app.data.time) - (app.dt*30)
                plot(app.UIAxes,app.data.position(app.this_idx:app.this_idx+(app.dt*30),1), app.data.position(app.this_idx:app.this_idx+(app.dt*30),2), '.','color', [app.blue .2], 'markersize', 4)
            else
                plot(app.UIAxes,app.data.position(app.this_idx:app.this_idx(end),1), app.data.position(app.this_idx:app.this_idx(end),2), '.','color', [app.blue .2], 'markersize', 4)
            end
            if app.this_idx < app.dt*5
                plot(app.UIAxes,app.data.position(1:app.this_idx,1), app.data.position(1:app.this_idx,2), '.r', 'markersize', 8)
            else
                plot(app.UIAxes,app.data.position(app.this_idx-(app.dt*5):app.this_idx,1), app.data.position(app.this_idx-(app.dt*5):app.this_idx,2), '.r', 'markersize', 8)
            end
            plot(app.UIAxes,app.data.position(app.this_idx,1), app.data.position(app.this_idx,2), 'or', 'markersize', 14)
            app.UIAxes.Title.String = ['Time: ' num2str(app.data.time(app.this_idx)) 's'];
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            app.Slider.Limits = app.t_range;
            value = app.Slider.Value;
            app.this_idx =  nearest_idx3(value, app.data.time);
            cla(app.UIAxes)
            plot(app.UIAxes,app.data.position(:,1), app.data.position(:,2), '.', 'color', [0.9 .9 .9])
            hold(app.UIAxes, 'on')
             if app.this_idx < length(app.data.time) - (app.dt*30)
                plot(app.UIAxes,app.data.position(app.this_idx:app.this_idx+(app.dt*30),1), app.data.position(app.this_idx:app.this_idx+(app.dt*30),2), '.','color', [app.blue .2], 'markersize', 4)
            else
                plot(app.UIAxes,app.data.position(app.this_idx:app.this_idx(end),1), app.data.position(app.this_idx:app.this_idx(end),2), '.','color', [app.blue .2], 'markersize', 4)
            end
            if app.this_idx < app.dt*5
                plot(app.UIAxes,app.data.position(1:app.this_idx,1), app.data.position(1:app.this_idx,2), '.r', 'markersize', 8)
            else
                plot(app.UIAxes,app.data.position(app.this_idx-(app.dt*5):app.this_idx,1), app.data.position(app.this_idx-(app.dt*5):app.this_idx,2), '.r', 'markersize', 8)
            end
            plot(app.UIAxes,app.data.position(app.this_idx,1), app.data.position(app.this_idx,2), 'or', 'markersize', 14)
            app.UIAxes.Title.String = ['Time: ' num2str(app.data.time(app.this_idx)) 's'];
        end

        % Value changed function: stepsiteEditField
        function stepsiteEditFieldValueChanged(app, event)
            %app.stepsiteEditField.Value = 1;
            value = app.stepsiteEditField.Value;
            
        end

        % Button pushed function: Box_in
        function Box_inButtonPushed(app, event)
            disp([app.labels{1} ':' num2str(app.data.time(app.this_idx)/1000, 5)])
            app.states(end+1) = 1;
            app.states_times(end+1) = app.data.time(app.this_idx);
            for ii = 0:length(app.states_times)-1
                if app.states(end-ii) == 8
                    app.TextArea.Value{ii+1} = [num2str(app.states_times(end-ii)/1000, 5) '  |  ' app.labels{app.states(end-ii)} '  |  ' num2str(app.states(end-ii)) '  |  ' app.notes{end-ii}{1}];
                else
                    app.TextArea.Value{ii+1} = [num2str(app.states_times(end-ii)/1000, 5) '  |  ' app.labels{app.states(end-ii)} '  |  ' num2str(app.states(end-ii)) ];
                end
            end
        end

        % Button pushed function: C_L
        function C_LButtonPushed(app, event)
            disp([app.labels{2} ':' num2str(app.data.time(app.this_idx)/1000, 5)])
            app.states(end+1) = 2;
            app.states_times(end+1) = app.data.time(app.this_idx);
            for ii = 0:length(app.states_times)-1
                if app.states(end-ii) == 8
                    app.TextArea.Value{ii+1} = [num2str(app.states_times(end-ii)/1000, 5) '  |  ' app.labels{app.states(end-ii)} '  |  ' num2str(app.states(end-ii)) '  |  ' app.notes{end-ii}{1}];
                else
                    app.TextArea.Value{ii+1} = [num2str(app.states_times(end-ii)/1000, 5) '  |  ' app.labels{app.states(end-ii)} '  |  ' num2str(app.states(end-ii)) ];
                end
            end
        end

        % Button pushed function: C_R
        function C_RButtonPushed(app, event)
            disp([app.labels{3} ':' num2str(app.data.time(app.this_idx)/1000, 5)])
            app.states(end+1) = 3;
            app.states_times(end+1) = app.data.time(app.this_idx);
            for ii = 0:length(app.states_times)-1
                if app.states(end-ii) == 8
                    app.TextArea.Value{ii+1} = [num2str(app.states_times(end-ii)/1000, 5) '  |  ' app.labels{app.states(end-ii)} '  |  ' num2str(app.states(end-ii)) '  |  ' app.notes{end-ii}{1}];
                else
                    app.TextArea.Value{ii+1} = [num2str(app.states_times(end-ii)/1000, 5) '  |  ' app.labels{app.states(end-ii)} '  |  ' num2str(app.states(end-ii)) ];
                end
            end
        end

        % Button pushed function: F_L
        function F_LButtonPushed(app, event)
            disp([app.labels{4} ':' num2str(app.data.time(app.this_idx)/1000, 5)])
            app.states(end+1) = 4;
            app.states_times(end+1) = app.data.time(app.this_idx);
            for ii = 0:length(app.states_times)-1
                if app.states(end-ii) == 8
                    app.TextArea.Value{ii+1} = [num2str(app.states_times(end-ii)/1000, 5) '  |  ' app.labels{app.states(end-ii)} '  |  ' num2str(app.states(end-ii)) '  |  ' app.notes{end-ii}{1}];
                else
                    app.TextArea.Value{ii+1} = [num2str(app.states_times(end-ii)/1000, 5) '  |  ' app.labels{app.states(end-ii)} '  |  ' num2str(app.states(end-ii)) ];
                end
            end
        end

        % Button pushed function: F_R
        function F_RButtonPushed(app, event)
            disp([app.labels{5} ':' num2str(app.data.time(app.this_idx)/1000, 5)])
            app.states(end+1) = 5;
            app.states_times(end+1) = app.data.time(app.this_idx);
            for ii = 0:length(app.states_times)-1
                if app.states(end-ii) == 8
                    app.TextArea.Value{ii+1} = [num2str(app.states_times(end-ii)/1000, 5) '  |  ' app.labels{app.states(end-ii)} '  |  ' num2str(app.states(end-ii)) '  |  ' app.notes{end-ii}{1}];
                else
                    app.TextArea.Value{ii+1} = [num2str(app.states_times(end-ii)/1000, 5) '  |  ' app.labels{app.states(end-ii)} '  |  ' num2str(app.states(end-ii)) ];
                end
            end
        end

        % Button pushed function: R_Rew
        function R_RewButtonPushed(app, event)
            disp([app.labels{6} ':' num2str(app.data.time(app.this_idx)/1000, 5)])
            app.states(end+1) = 6;
            app.states_times(end+1) = app.data.time(app.this_idx);
            for ii = 0:length(app.states_times)-1
                if app.states(end-ii) == 8
                    app.TextArea.Value{ii+1} = [num2str(app.states_times(end-ii)/1000, 5) '  |  ' app.labels{app.states(end-ii)} '  |  ' num2str(app.states(end-ii)) '  |  ' app.notes{end-ii}{1}];
                else
                    app.TextArea.Value{ii+1} = [num2str(app.states_times(end-ii)/1000, 5) '  |  ' app.labels{app.states(end-ii)} '  |  ' num2str(app.states(end-ii)) ];
                end
            end
        end

        % Button pushed function: L_Rew
        function L_RewButtonPushed(app, event)
            disp([app.labels{7} ':' num2str(app.data.time(app.this_idx)/1000, 5)])
            app.states(end+1) = 7;
            app.states_times(end+1) = app.data.time(app.this_idx);
            for ii = 0:length(app.states_times)-1
                if app.states(end-ii) == 8
                    app.TextArea.Value{ii+1} = [num2str(app.states_times(end-ii)/1000, 5) '  |  ' app.labels{app.states(end-ii)} '  |  ' num2str(app.states(end-ii)) '  |  ' app.notes{end-ii}{1}];
                else
                    app.TextArea.Value{ii+1} = [num2str(app.states_times(end-ii)/1000, 5) '  |  ' app.labels{app.states(end-ii)} '  |  ' num2str(app.states(end-ii)) ];
                end
            end
        end

        % Button pushed function: NoteButton
        function NoteButtonPushed(app, event)
            disp([app.labels{8} ':' num2str(app.data.time(app.this_idx)/1000, 5)])
            app.states(end+1) = 8;
            app.states_times(end+1) = app.data.time(app.this_idx);
            app.notes{end+1} = app.NoteTextArea.Value;
            for ii = 0:length(app.states_times)-1
                if app.states(end-ii) == 8
                    app.TextArea.Value{ii+1} = [num2str(app.states_times(end-ii)/1000, 5) '  |  ' app.labels{app.states(end-ii)} '  |  ' num2str(app.states(end-ii)) '  |  ' app.notes{end-ii}{1}];
                else
                    app.TextArea.Value{ii+1} = [num2str(app.states_times(end-ii)/1000, 5) '  |  ' app.labels{app.states(end-ii)} '  |  ' num2str(app.states(end-ii)) ];
                end
            end
        end

        % Button pushed function: UndoButton
        function UndoButtonPushed(app, event)
            disp('Removing last event')
            app.states = app.states(1:end-1);
            %             app.states_vec = app.states_vec(1:end-1);
            app.states_times = app.states_times(1:end-1); 
            for ii = 0:length(app.states_times)-1
                app.TextArea.Value{ii+1} = [num2str(app.states_times(end-ii)/1000, 5) '  |  ' app.labels{app.states(end-ii)} '  |  ' num2str(app.states(end-ii))];
            end
        end

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)
            value = app.TextArea.Value;
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.states_label = [];
            for ii = length(app.states):-1:1
                app.states_label{ii} = app.labels{app.states(ii)};
            end
            assignin("base",'GUI_states', app.states_label)
            assignin("base",'GUI_state_times', app.states_times)
            
        end

        % Button pushed function: CloseButton
        function Close(app, event)
            delete(app)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {502, 502};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {475, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.LeftPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.Slider = uislider(app.LeftPanel);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.PrevButton = uibutton(app.LeftPanel, 'push');
            app.PrevButton.ButtonPushedFcn = createCallbackFcn(app, @PrevButtonPushed, true);
            app.PrevButton.Text = 'Prev';
            app.NextButton = uibutton(app.LeftPanel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.stepsiteEditFieldLabel = uilabel(app.LeftPanel);
            app.stepsiteEditFieldLabel.Text = 'step site';
            app.stepsiteEditField = uieditfield(app.LeftPanel, 'numeric');
            app.stepsiteEditField.ValueChangedFcn = createCallbackFcn(app, @stepsiteEditFieldValueChanged, true);
            app.Next10xButton = uibutton(app.LeftPanel, 'push');
            app.Next10xButton.ButtonPushedFcn = createCallbackFcn(app, @Next10xButtonPushed, true);
            app.Next10xButton.Text = 'Next 10x';
            app.Prev10xButton = uibutton(app.LeftPanel, 'push');
            app.Prev10xButton.ButtonPushedFcn = createCallbackFcn(app, @Prev10xButtonPushed, true);
            app.Prev10xButton.Text = 'Prev 10x';
            app.TextArea = uitextarea(app.LeftPanel);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.Box_in = uibutton(app.RightPanel, 'push');
            app.Box_in.ButtonPushedFcn = createCallbackFcn(app, @Box_inButtonPushed, true);
            app.Box_in.Text = 'Box in';
            app.C_L = uibutton(app.RightPanel, 'push');
            app.C_L.ButtonPushedFcn = createCallbackFcn(app, @C_LButtonPushed, true);
            app.C_L.Text = 'C_L';
            app.C_R = uibutton(app.RightPanel, 'push');
            app.C_R.ButtonPushedFcn = createCallbackFcn(app, @C_RButtonPushed, true);
            app.C_R.Text = 'C_R';
            app.F_L = uibutton(app.RightPanel, 'push');
            app.F_L.ButtonPushedFcn = createCallbackFcn(app, @F_LButtonPushed, true);
            app.F_L.Text = 'F_L';
            app.F_R = uibutton(app.RightPanel, 'push');
            app.F_R.ButtonPushedFcn = createCallbackFcn(app, @F_RButtonPushed, true);
            app.F_R.Text = 'F_R';
            app.R_Rew = uibutton(app.RightPanel, 'push');
            app.R_Rew.ButtonPushedFcn = createCallbackFcn(app, @R_RewButtonPushed, true);
            app.R_Rew.Text = 'R_Rew';
            app.L_Rew = uibutton(app.RightPanel, 'push');
            app.L_Rew.ButtonPushedFcn = createCallbackFcn(app, @L_RewButtonPushed, true);
            app.L_Rew.Text = 'L_Rew';
            app.NoteTextArea = uitextarea(app.RightPanel);
            app.NoteButton = uibutton(app.RightPanel, 'push');
            app.NoteButton.ButtonPushedFcn = createCallbackFcn(app, @NoteButtonPushed, true);
            app.NoteButton.Text = 'Note';
            app.UndoButton = uibutton(app.RightPanel, 'push');
            app.UndoButton.ButtonPushedFcn = createCallbackFcn(app, @UndoButtonPushed, true);
            app.UndoButton.Text = 'Undo';
            app.LoadData = uibutton(app.RightPanel, 'push');
            app.LoadData.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadData.Text = 'Load';
            app.SaveButton = uibutton(app.RightPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.CloseButton = uibutton(app.RightPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @Close, true);
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Maze_GUI_DLC_sandbox

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end