classdef Maze_GUI_sandbox < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        LeftPanel               matlab.ui.container.Panel
        UIAxes                  matlab.ui.control.UIAxes
        Slider                  matlab.ui.control.Slider
        NextButton              matlab.ui.control.Button
        PrevButton              matlab.ui.control.Button
        LoadData                matlab.ui.control.Button
        stepsiteEditFieldLabel  matlab.ui.control.Label
        stepsiteEditField       matlab.ui.control.NumericEditField
        RightPanel              matlab.ui.container.Panel
        Box_in                  matlab.ui.control.Button
        C_L                     matlab.ui.control.Button
        C_R                     matlab.ui.control.Button
        F_L                     matlab.ui.control.Button
        F_R                     matlab.ui.control.Button
        R_Rew                   matlab.ui.control.Button
        L_Rew                   matlab.ui.control.Button
        NoteTextArea            matlab.ui.control.TextArea
        NoteButton              matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        this_idx = [] % Description
        data = [];
        dt = [];
        t_range = [];
        states = [];
        state_tvec = []; 
        labels = {'Box_in', 'C_L', 'C_L', 'F_L', 'F_R', 'R_Rew', 'L_Rew'};
        notes = {}; 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadData
        function LoadDataButtonPushed(app, event)
            app.data = evalin('base', 'behav');
            app.dt = mode(diff(app.data.time));
            app.t_range = [app.data.time(1), app.data.time(end)]; 
            app.state_tvec = nan(1,length(app.data.time));
            app.this_idx = 1;
            plot(app.UIAxes,app.data.position(:,1), app.data.position(:,2), '.', 'color', [0.9 .9 .9]);
             hold(app.UIAxes, 'on')
            plot(app.UIAxes,app.data.position(app.this_idx,1), app.data.position(app.this_idx,2), 'or', 'markersize', 14)
            this_title = ['Time: ' num2str(app.data.time(app.this_idx)/1000) 's']; 
            app.UIAxes.Title.String =this_title ;
            app.Slider.Limits = app.t_range; 
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            cla(app.UIAxes)
            app.this_idx = app.this_idx+(round(app.dt*app.stepsiteEditField.Value));
            plot(app.UIAxes,app.data.position(:,1), app.data.position(:,2), '.', 'color', [0.9 .9 .9])
            hold(app.UIAxes, 'on')
            plot(app.UIAxes,app.data.position(app.this_idx,1), app.data.position(app.this_idx,2), 'or', 'markersize', 14)
            app.UIAxes.Title.String = ['Time: ' num2str(app.data.time(app.this_idx)/1000) 's'];
        end

        % Button pushed function: PrevButton
        function PrevButtonPushed(app, event)
            cla(app.UIAxes)
            app.this_idx = app.this_idx-(round(app.dt*app.stepsiteEditField.Value));
            plot(app.UIAxes,app.data.position(:,1), app.data.position(:,2), '.', 'color', [0.9 .9 .9])
            hold(app.UIAxes, 'on')
            plot(app.UIAxes,app.data.position(app.this_idx,1), app.data.position(app.this_idx,2), 'or', 'markersize', 14)
            app.UIAxes.Title.String = ['Time: ' num2str(app.data.time(app.this_idx)/1000) 's'];
     
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            app.Slider.Limits = app.t_range; 
            value = app.Slider.Value;
            app.this_idx =  nearest_idx3(value, app.data.time);
                        cla(app.UIAxes)
            plot(app.UIAxes,app.data.position(:,1), app.data.position(:,2), '.', 'color', [0.9 .9 .9])
            hold(app.UIAxes, 'on')
            plot(app.UIAxes,app.data.position(app.this_idx,1), app.data.position(app.this_idx,2), 'or', 'markersize', 14)
            
        end

        % Value changed function: stepsiteEditField
        function stepsiteEditFieldValueChanged(app, event)
            %app.stepsiteEditField.Value = 1; 
            value = app.stepsiteEditField.Value;
            
        end

        % Button pushed function: Box_in
        function Box_inButtonPushed(app, event)
            disp([app.labels{1} ':' num2str(app.data.time(app.this_idx)/1000, 2)])
            app.states(app.this_idx) = 1; 
            app.state_tvec(app.this_idx) = app.data.time(app.this_idx); 
        end

        % Button pushed function: C_L
        function C_LButtonPushed(app, event)
            disp([app.labels{2} ':' num2str(app.data.time(app.this_idx)/1000, 2)])
            app.states(end+1) = 2; 
            app.state_tvec(end+1) = app.data.time(app.this_idx);
        end

        % Button pushed function: NoteButton
        function NoteButtonPushed(app, event)
            disp([num2str(app.data.time(app.this_idx)/1000, 2) ' - ' app.NoteTextArea.Value])
            app.notes{end+1} =  app.NoteTextArea.Value;
            app.notes_tvec(end+1) = app.data.time(app.this_idx); 
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {504, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.LeftPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Slider = uislider(app.LeftPanel);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.PrevButton = uibutton(app.LeftPanel, 'push');
            app.PrevButton.ButtonPushedFcn = createCallbackFcn(app, @PrevButtonPushed, true);
            app.PrevButton.Text = 'Prev';
            app.NextButton = uibutton(app.LeftPanel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.LoadData = uibutton(app.LeftPanel, 'push');
            app.LoadData.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadData.Text = 'Load';
            app.stepsiteEditFieldLabel = uilabel(app.LeftPanel);
            app.stepsiteEditFieldLabel.Text = 'step site';
            app.stepsiteEditField = uieditfield(app.LeftPanel, 'numeric');
            app.stepsiteEditField.ValueChangedFcn = createCallbackFcn(app, @stepsiteEditFieldValueChanged, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.Box_in = uibutton(app.RightPanel, 'push');
            app.Box_in.ButtonPushedFcn = createCallbackFcn(app, @Box_inButtonPushed, true);
            app.Box_in.Text = 'Box in';
            app.C_L = uibutton(app.RightPanel, 'push');
            app.C_L.ButtonPushedFcn = createCallbackFcn(app, @C_LButtonPushed, true);
            app.C_L.Text = 'C_L';
            app.C_R = uibutton(app.RightPanel, 'push');
            app.C_R.Text = 'C_R';
            app.F_L = uibutton(app.RightPanel, 'push');
            app.F_L.Text = 'F_L';
            app.F_R = uibutton(app.RightPanel, 'push');
            app.F_R.Text = 'F_R';
            app.R_Rew = uibutton(app.RightPanel, 'push');
            app.R_Rew.Text = 'R_Rew';
            app.L_Rew = uibutton(app.RightPanel, 'push');
            app.L_Rew.Text = 'L_Rew';
            app.NoteTextArea = uitextarea(app.RightPanel);
            app.NoteButton = uibutton(app.RightPanel, 'push');
            app.NoteButton.ButtonPushedFcn = createCallbackFcn(app, @NoteButtonPushed, true);
            app.NoteButton.Text = 'Note';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Maze_GUI_sandbox

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end